classdef attiqub_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChiefSpacecraftAttitudeButtonGroup  matlab.ui.container.ButtonGroup
        ModifiedRodriguesParametersButton  matlab.ui.control.ToggleButton
        ClassicalRodriguesParametersButton  matlab.ui.control.ToggleButton
        QuarternionsButton              matlab.ui.control.ToggleButton
        ChiefSpacecraftOrbitLabel       matlab.ui.control.Label
        DeputySpacecraftOrbitLabel      matlab.ui.control.Label
        InitialAttitudeLabel            matlab.ui.control.Label
        InitialOmegaLabel               matlab.ui.control.Label
        ControlLawButtonGroup           matlab.ui.container.ButtonGroup
        PDFeedbackControlButton         matlab.ui.control.ToggleButton
        PIDFeedbackControlButton        matlab.ui.control.ToggleButton
        ModelPredictiveControlButton    matlab.ui.control.ToggleButton
        ControlModeButtonGroup          matlab.ui.container.ButtonGroup
        NadirPointingButton             matlab.ui.control.ToggleButton
        SunPointingButton               matlab.ui.control.ToggleButton
        InterSatelliteCommsButton       matlab.ui.control.ToggleButton
        DeTumblingButton                matlab.ui.control.ToggleButton
        LAUNCHButton                    matlab.ui.control.Button
        SimulationSetupLabel            matlab.ui.control.Label
        ReactionWheelAssemblyLabel      matlab.ui.control.Label
        SimulationTimesEditFieldLabel   matlab.ui.control.Label
        SimulationTimesEditField        matlab.ui.control.NumericEditField
        TimeStepsEditFieldLabel         matlab.ui.control.Label
        TimeStepsEditField              matlab.ui.control.NumericEditField
        CentralBodyDropDownLabel        matlab.ui.control.Label
        CentralBodyDropDown             matlab.ui.control.DropDown
        SemiMajorAxismEditFieldLabel    matlab.ui.control.Label
        SemiMajorAxismEditField         matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel      matlab.ui.control.Label
        EccentricityEditField           matlab.ui.control.NumericEditField
        InclinationdegEditFieldLabel    matlab.ui.control.Label
        InclinationdegEditField         matlab.ui.control.NumericEditField
        RAANdegEditFieldLabel           matlab.ui.control.Label
        RAANdegEditField                matlab.ui.control.NumericEditField
        ArgPeriapsisdegEditFieldLabel   matlab.ui.control.Label
        ArgPeriapsisdegEditField        matlab.ui.control.NumericEditField
        MeanAnomalydegEditFieldLabel    matlab.ui.control.Label
        MeanAnomalydegEditField         matlab.ui.control.NumericEditField
        A0EditFieldLabel                matlab.ui.control.Label
        A0EditField                     matlab.ui.control.NumericEditField
        A1EditField_5Label              matlab.ui.control.Label
        A1EditField                     matlab.ui.control.NumericEditField
        A2EditFieldLabel                matlab.ui.control.Label
        A2EditField                     matlab.ui.control.NumericEditField
        A3EditFieldLabel                matlab.ui.control.Label
        A3EditField                     matlab.ui.control.NumericEditField
        O1EditFieldLabel                matlab.ui.control.Label
        O1EditField                     matlab.ui.control.NumericEditField
        O2EditFieldLabel                matlab.ui.control.Label
        O2EditField                     matlab.ui.control.NumericEditField
        O3EditFieldLabel                matlab.ui.control.Label
        O3EditField                     matlab.ui.control.NumericEditField
        SemiMajorAxismEditField_2Label  matlab.ui.control.Label
        SemiMajorAxismEditField_2       matlab.ui.control.NumericEditField
        EccentricityEditField_2Label    matlab.ui.control.Label
        EccentricityEditField_2         matlab.ui.control.NumericEditField
        InclinationdegEditField_2Label  matlab.ui.control.Label
        InclinationdegEditField_2       matlab.ui.control.NumericEditField
        RAANdegEditField_2Label         matlab.ui.control.Label
        RAANdegEditField_2              matlab.ui.control.NumericEditField
        ArgPeriapsisdegEditField_2Label  matlab.ui.control.Label
        ArgPeriapsisdegEditField_2      matlab.ui.control.NumericEditField
        MeanAnomalydegEditField_2Label  matlab.ui.control.Label
        MeanAnomalydegEditField_2       matlab.ui.control.NumericEditField
        KPEditFieldLabel                matlab.ui.control.Label
        KPEditField                     matlab.ui.control.NumericEditField
        KIEditFieldLabel                matlab.ui.control.Label
        KIEditField                     matlab.ui.control.NumericEditField
        KDEditFieldLabel                matlab.ui.control.Label
        KDEditField                     matlab.ui.control.NumericEditField
        ControlRateHzEditFieldLabel     matlab.ui.control.Label
        ControlRateHzEditField          matlab.ui.control.NumericEditField
        NoReactionWheelsEditFieldLabel  matlab.ui.control.Label
        NoReactionWheelsEditField       matlab.ui.control.NumericEditField
        MaximumSpinTorqueEditFieldLabel  matlab.ui.control.Label
        MaximumSpinTorqueEditField      matlab.ui.control.NumericEditField
        MaximumSpinRateEditFieldLabel   matlab.ui.control.Label
        MaximumSpinRateEditField        matlab.ui.control.NumericEditField
        ReactionWheelSpinAxesLabel      matlab.ui.control.Label
        X1EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        Y1EditFieldLabel                matlab.ui.control.Label
        Y1EditField                     matlab.ui.control.NumericEditField
        Z1EditFieldLabel                matlab.ui.control.Label
        Z1EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        Y2EditFieldLabel                matlab.ui.control.Label
        Y2EditField                     matlab.ui.control.NumericEditField
        Z2EditFieldLabel                matlab.ui.control.Label
        Z2EditField                     matlab.ui.control.NumericEditField
        X3EditFieldLabel                matlab.ui.control.Label
        X3EditField                     matlab.ui.control.NumericEditField
        Y3EditFieldLabel                matlab.ui.control.Label
        Y3EditField                     matlab.ui.control.NumericEditField
        Z3EditFieldLabel                matlab.ui.control.Label
        Z3EditField                     matlab.ui.control.NumericEditField
        X4EditFieldLabel                matlab.ui.control.Label
        X4EditField                     matlab.ui.control.NumericEditField
        Y4EditFieldLabel                matlab.ui.control.Label
        Y4EditField                     matlab.ui.control.NumericEditField
        Z4EditFieldLabel                matlab.ui.control.Label
        Z4EditField                     matlab.ui.control.NumericEditField
        X5EditFieldLabel                matlab.ui.control.Label
        X5EditField                     matlab.ui.control.NumericEditField
        Y5EditFieldLabel                matlab.ui.control.Label
        Y5EditField                     matlab.ui.control.NumericEditField
        Z5EditFieldLabel                matlab.ui.control.Label
        Z5EditField                     matlab.ui.control.NumericEditField
        X6EditFieldLabel                matlab.ui.control.Label
        X6EditField                     matlab.ui.control.NumericEditField
        Y6EditFieldLabel                matlab.ui.control.Label
        Y6EditField                     matlab.ui.control.NumericEditField
        Z6EditFieldLabel                matlab.ui.control.Label
        Z6EditField                     matlab.ui.control.NumericEditField
        X7EditFieldLabel                matlab.ui.control.Label
        X7EditField                     matlab.ui.control.NumericEditField
        Y7EditFieldLabel                matlab.ui.control.Label
        Y7EditField                     matlab.ui.control.NumericEditField
        Z7EditFieldLabel                matlab.ui.control.Label
        Z7EditField                     matlab.ui.control.NumericEditField
        X8EditFieldLabel                matlab.ui.control.Label
        X8EditField                     matlab.ui.control.NumericEditField
        Y8EditFieldLabel                matlab.ui.control.Label
        Y8EditField                     matlab.ui.control.NumericEditField
        Z8EditFieldLabel                matlab.ui.control.Label
        Z8EditField                     matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        DisturbanceTorqueNmLabel        matlab.ui.control.Label
        TXEditFieldLabel                matlab.ui.control.Label
        TXEditField                     matlab.ui.control.NumericEditField
        TYEditFieldLabel                matlab.ui.control.Label
        TYEditField                     matlab.ui.control.NumericEditField
        TZEditFieldLabel                matlab.ui.control.Label
        TZEditField                     matlab.ui.control.NumericEditField
        SAVEPARAMETERSButton            matlab.ui.control.Button
        LOADPARAMETERSButton            matlab.ui.control.Button
        ChiefPrincipalInertiasLabel     matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        IXEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        IYEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        IZEditField                     matlab.ui.control.NumericEditField
        V1EditFieldLabel                matlab.ui.control.Label
        V1EditField                     matlab.ui.control.NumericEditField
        RateRPMLabel                    matlab.ui.control.Label
        InitialSpinLabel                matlab.ui.control.Label
        V2EditFieldLabel                matlab.ui.control.Label
        V2EditField                     matlab.ui.control.NumericEditField
        V3EditFieldLabel                matlab.ui.control.Label
        V3EditField                     matlab.ui.control.NumericEditField
        V4EditFieldLabel                matlab.ui.control.Label
        V4EditField                     matlab.ui.control.NumericEditField
        V5EditFieldLabel                matlab.ui.control.Label
        V5EditField                     matlab.ui.control.NumericEditField
        V6EditFieldLabel                matlab.ui.control.Label
        V6EditField                     matlab.ui.control.NumericEditField
        V7EditFieldLabel                matlab.ui.control.Label
        V7EditField                     matlab.ui.control.NumericEditField
        V8EditFieldLabel                matlab.ui.control.Label
        V8EditField                     matlab.ui.control.NumericEditField
        Version05LastUpdated17April2021Label  matlab.ui.control.Label
        IndividualReactionWheelPrincipalInertiakgm2Label  matlab.ui.control.Label
        RXEditFieldLabel                matlab.ui.control.Label
        RXEditField                     matlab.ui.control.NumericEditField
        RYEditFieldLabel                matlab.ui.control.Label
        RYEditField                     matlab.ui.control.NumericEditField
        RZEditFieldLabel                matlab.ui.control.Label
        RZEditField                     matlab.ui.control.NumericEditField
    end

    % Create a save and load function for the app.
    methods (Access = private)
        
        % Save function.
        function saveState(app)
            
            % Save the simulation time duration and steps.
            attiqubState.tFinal = app.SimulationTimesEditField.Value;
            attiqubState.tStepD = app.TimeStepsEditField.Value;
            attiqubState.tStepC = 1 / app.ControlRateHzEditField.Value;
            
            % Save the choice of central body.
            if app.CentralBodyDropDown.Value == '1'
                attiqubState.body = 1;
            elseif app.CentralBodyDropDown.Value == '2'
                attiqubState.body = 2;
            elseif app.CentralBodyDropDown.Value == '3'
                attiqubState.body = 3;
            elseif app.CentralBodyDropDown.Value == '4'
                attiqubState.body = 4;
            elseif app.CentralBodyDropDown.Value == '5'
                attiqubState.body = 5;
            else
                attiqubState.body = 1;
            end
            
            % Save the spacecraft principal inertias.
            satInertia = diag( [ app.IXEditField.Value ...
                                 app.IYEditField.Value ...
                                 app.IZEditField.Value ] );
            attiqubState.satInertia = satInertia;
            
            % Save user defined attitude coordinate choices.
            if app.QuarternionsButton.Value == true
                attiqubState.satAttCoord = 1;
                app.A0EditField.Enable = 'On';
                app.A0EditField.Editable = 'On';
            elseif app.ClassicalRodriguesParametersButton.Value == true
                attiqubState.satAttCoord = 2;
                app.A0EditField.Enable = 'Off';
                app.A0EditField.Editable = 'Off';
            elseif app.ModifiedRodriguesParametersButton.Value == true
                attiqubState.satAttCoord = 3;
                app.A0EditField.Enable = 'Off';
                app.A0EditField.Editable = 'Off';
            else
                attiqubState.satAttCoord = 3;
            end
            
            % Save user defined control law choices.
            if app.PDFeedbackControlButton.Value == true
                attiqubState.ctrlLaw = 1;
            elseif app.PIDFeedbackControlButton.Value == true
                attiqubState.ctrlLaw = 2;
            elseif app.ModelPredictiveControlButton.Value == true
                attiqubState.ctrlLaw = 3;
            else
                attiqubState.ctrlLaw = 1;
            end
            
            % Save user defined control mode choices.
            if app.NadirPointingButton.Value == true
                attiqubState.satPointing = 1;
            elseif app.SunPointingButton.Value == true
                attiqubState.satPointing = 2;
            elseif app.InterSatelliteCommsButton.Value == true
                attiqubState.satPointing = 3;
            elseif app.DeTumblingButton.Value == true
                attiqubState.satPointing = 4;
            else
                attiqubState.satPointing = 4;
            end
            
            % Save control gains (for PD and PID mode).
            attiqubState.Kp = app.KPEditField.Value;
            attiqubState.Ki = app.KIEditField.Value;
            attiqubState.Kd = app.KDEditField.Value;
            
            % Save the chief spacecraft orbit.
            attiqubState.orb1A = app.SemiMajorAxismEditField.Value;
            attiqubState.orb1E = app.EccentricityEditField.Value;
            attiqubState.orb1I = app.InclinationdegEditField.Value;
            attiqubState.orb1R = app.RAANdegEditField.Value;
            attiqubState.orb1W = app.ArgPeriapsisdegEditField.Value;
            attiqubState.orb1M = app.MeanAnomalydegEditField.Value;
            
            % Save the deputy spacecraft orbit.
            attiqubState.orb2A = app.SemiMajorAxismEditField_2.Value;
            attiqubState.orb2E = app.EccentricityEditField_2.Value;
            attiqubState.orb2I = app.InclinationdegEditField_2.Value;
            attiqubState.orb2R = app.RAANdegEditField_2.Value;
            attiqubState.orb2W = app.ArgPeriapsisdegEditField_2.Value;
            attiqubState.orb2M = app.MeanAnomalydegEditField_2.Value;
            
            % Save the attitude coordinates.
            A0 = app.A0EditField.Value;
            A1 = app.A1EditField.Value;
            A2 = app.A2EditField.Value;
            A3 = app.A3EditField.Value;
            
            % Normalize for the case of quarternions.
            if attiqubState.satAttCoord == 1
                if ( [ A0 A1 A2 A3 ] * [ A0 A1 A2 A3 ]' ) ~= 1
                    att = [ A0 A1 A2 A3 ] / norm( [ A0 A1 A2 A3 ] );
                    app.A0EditField.Value = att(1);
                    app.A1EditField.Value = att(2);
                    app.A2EditField.Value = att(3);
                    app.A3EditField.Value = att(4);
                else
                    att = [ A0 A1 A2 A3 ];
                end
                attiqubState.ATT_BN = att;
                
            
            else
                att = [ A1 A2 A3 ];
                
                % Normalize for the case of CRPs
                if attiqubState.satAttCoord == 2
                    if ( [ A1 A2 A3 ] * [ A1 A2 A3 ]' ) == 0
                        attiqubState.ATT_BN = [ 0 0 0 ];
                        app.A0EditField.Value = 1;
                        app.A1EditField.Value = 0;
                        app.A2EditField.Value = 0;
                        app.A3EditField.Value = 0;
                    else
                        att_qrt = crp_conv2qrt(att);
                        att_qrt = att_qrt / norm( att_qrt );
                        att = qrt_conv2crp( att_qrt );
                        app.A0EditField.Value = 0;
                        app.A1EditField.Value = att(1);
                        app.A2EditField.Value = att(2);
                        app.A3EditField.Value = att(3);
                    end
                    
                % Normalize for the case of MRPs
                elseif attiqubState.satAttCoord == 3
                    if ( [ A1 A2 A3 ] * [ A1 A2 A3 ]' ) == 0
                        attiqubState.ATT_BN = [ 0 0 0 ];
                        app.A0EditField.Value = 1;
                        app.A1EditField.Value = 0;
                        app.A2EditField.Value = 0;
                        app.A3EditField.Value = 0;
                    else
                        att_qrt = mrp_conv2qrt(att);
                        att_qrt = att_qrt / norm( att_qrt );
                        att = qrt_conv2mrp( att_qrt );
                        app.A0EditField.Value = 0;
                        app.A1EditField.Value = att(1);
                        app.A2EditField.Value = att(2);
                        app.A3EditField.Value = att(3);
                    end
                end
                
                attiqubState.ATT_BN = att;
            end
            
            % Save the angular velocities.
            O1 = app.O1EditField.Value;
            O2 = app.O2EditField.Value;
            O3 = app.O3EditField.Value;
            attiqubState.OHM_BN = [ O1 O2 O3 ];
            
            % Save the reaction wheel parameters.
            attiqubState.RW_Num = app.NoReactionWheelsEditField.Value;
            attiqubState.RW_MxT = app.MaximumSpinTorqueEditField.Value;
            attiqubState.RW_MxR = app.MaximumSpinRateEditField.Value;
            
            % Set the RW spin axes edit fields based on RW_Num
            app.disable_RWGsx();
            attiqubState.RW_Gsx = zeros( attiqubState.RW_Num, 3 );
            attiqubState.RW_Ohm = zeros( attiqubState.RW_Num, 1 );
            
            % Save the reaction wheel spin axes (from 1 to 8).
            if attiqubState.RW_Num >= 1
                Gsx1 = [ app.X1EditField.Value ...
                         app.Y1EditField.Value ...
                         app.Z1EditField.Value ];
                if ( Gsx1 * Gsx1' ) ~= 0
                    Gsx1 = Gsx1 / norm( Gsx1 );
                end
                app.X1EditField.Enable = 'On';
                app.X1EditField.Editable = 'On';
                app.X1EditField.Value = Gsx1(1);
                attiqubState.RW_Gsx(1,1) = Gsx1(1);
                app.Y1EditField.Enable = 'On';
                app.Y1EditField.Editable = 'On';
                app.Y1EditField.Value = Gsx1(2);
                attiqubState.RW_Gsx(1,2) = Gsx1(2);
                app.Z1EditField.Enable = 'On';
                app.Z1EditField.Editable = 'On';
                app.Z1EditField.Value = Gsx1(3);
                attiqubState.RW_Gsx(1,3) = Gsx1(3);
                app.V1EditField.Enable = 'On';
                app.V1EditField.Editable = 'On';
                attiqubState.RW_Ohm(1) = app.V1EditField.Value;
            end
            if attiqubState.RW_Num >= 2
                Gsx2 = [ app.X2EditField.Value ...
                         app.Y2EditField.Value ...
                         app.Z2EditField.Value ];
                if ( Gsx2 * Gsx2' ) ~= 0
                    Gsx2 = Gsx2 / norm( Gsx2 );
                end
                app.X2EditField.Enable = 'On';
                app.X2EditField.Editable = 'On';
                app.X2EditField.Value = Gsx2(1);
                attiqubState.RW_Gsx(2,1) = Gsx2(1);
                app.Y2EditField.Enable = 'On';
                app.Y2EditField.Editable = 'On';
                app.Y2EditField.Value = Gsx2(2);
                attiqubState.RW_Gsx(2,2) = Gsx2(2);
                app.Z2EditField.Enable = 'On';
                app.Z2EditField.Editable = 'On';
                app.Z2EditField.Value = Gsx2(3);
                attiqubState.RW_Gsx(2,3) = Gsx2(3);
                app.V2EditField.Enable = 'On';
                app.V2EditField.Editable = 'On';
                attiqubState.RW_Ohm(2) = app.V2EditField.Value;
            end
            if attiqubState.RW_Num >= 3
                Gsx3 = [ app.X3EditField.Value ...
                         app.Y3EditField.Value ...
                         app.Z3EditField.Value ];
                if ( Gsx3 * Gsx3' ) ~= 0
                    Gsx3 = Gsx3 / norm( Gsx3 );
                end
                app.X3EditField.Enable = 'On';
                app.X3EditField.Editable = 'On';
                app.X3EditField.Value = Gsx3(1);
                attiqubState.RW_Gsx(3,1) = Gsx3(1);
                app.Y3EditField.Enable = 'On';
                app.Y3EditField.Editable = 'On';
                app.Y3EditField.Value = Gsx3(2);
                attiqubState.RW_Gsx(3,2) = Gsx3(2);
                app.Z3EditField.Enable = 'On';
                app.Z3EditField.Editable = 'On';
                app.Z3EditField.Value = Gsx3(3);
                attiqubState.RW_Gsx(3,3) = Gsx3(3);
                app.V3EditField.Enable = 'On';
                app.V3EditField.Editable = 'On';
                attiqubState.RW_Ohm(3) = app.V3EditField.Value;
            end
            if attiqubState.RW_Num >= 4
                Gsx4 = [ app.X4EditField.Value ...
                         app.Y4EditField.Value ...
                         app.Z4EditField.Value ];
                if ( Gsx4 * Gsx4' ) ~= 0
                    Gsx4 = Gsx4 / norm( Gsx4 );
                end
                app.X4EditField.Enable = 'On';
                app.X4EditField.Editable = 'On';
                app.X4EditField.Value = Gsx4(1);
                attiqubState.RW_Gsx(4,1) = Gsx4(1);
                app.Y4EditField.Enable = 'On';
                app.Y4EditField.Editable = 'On';
                app.Y4EditField.Value = Gsx4(2);
                attiqubState.RW_Gsx(4,2) = Gsx4(2);
                app.Z4EditField.Enable = 'On';
                app.Z4EditField.Editable = 'On';
                app.Z4EditField.Value = Gsx4(3);
                attiqubState.RW_Gsx(4,3) = Gsx4(3);
                app.V4EditField.Enable = 'On';
                app.V4EditField.Editable = 'On';
                attiqubState.RW_Ohm(4) = app.V4EditField.Value;
            end
            if attiqubState.RW_Num >= 5
                Gsx5 = [ app.X5EditField.Value ...
                         app.Y5EditField.Value ...
                         app.Z5EditField.Value ];
                if ( Gsx5 * Gsx5' ) ~= 0
                    Gsx5 = Gsx5 / norm( Gsx5 );
                end
                app.X5EditField.Enable = 'On';
                app.X5EditField.Editable = 'On';
                app.X5EditField.Value = Gsx5(1);
                attiqubState.RW_Gsx(5,1) = Gsx5(1);
                app.Y5EditField.Enable = 'On';
                app.Y5EditField.Editable = 'On';
                app.Y5EditField.Value = Gsx5(2);
                attiqubState.RW_Gsx(5,2) = Gsx5(2);
                app.Z5EditField.Enable = 'On';
                app.Z5EditField.Editable = 'On';
                app.Z5EditField.Value = Gsx5(3);
                attiqubState.RW_Gsx(5,3) = Gsx5(3);
                app.V5EditField.Enable = 'On';
                app.V5EditField.Editable = 'On';
                attiqubState.RW_Ohm(5) = app.V5EditField.Value;
            end
            if attiqubState.RW_Num >= 6
                Gsx6 = [ app.X6EditField.Value ...
                         app.Y6EditField.Value ...
                         app.Z6EditField.Value ];
                if ( Gsx6 * Gsx6' ) ~= 0
                    Gsx6 = Gsx6 / norm( Gsx6 );
                end
                app.X6EditField.Enable = 'On';
                app.X6EditField.Editable = 'On';
                app.X6EditField.Value = Gsx6(1);
                attiqubState.RW_Gsx(6,1) = Gsx6(1);
                app.Y6EditField.Enable = 'On';
                app.Y6EditField.Editable = 'On';
                app.Y6EditField.Value = Gsx6(2);
                attiqubState.RW_Gsx(6,2) = Gsx6(2);
                app.Z6EditField.Enable = 'On';
                app.Z6EditField.Editable = 'On';
                app.Z6EditField.Value = Gsx6(3);
                attiqubState.RW_Gsx(6,3) = Gsx6(3);
                app.V6EditField.Enable = 'On';
                app.V6EditField.Editable = 'On';
                attiqubState.RW_Ohm(6) = app.V6EditField.Value;
            end
            if attiqubState.RW_Num >= 7
                Gsx7 = [ app.X7EditField.Value ...
                         app.Y7EditField.Value ...
                         app.Z7EditField.Value ];
                if ( Gsx7 * Gsx7' ) ~= 0
                    Gsx7 = Gsx7 / norm( Gsx7 );
                end
                app.X7EditField.Enable = 'On';
                app.X7EditField.Editable = 'On';
                app.X7EditField.Value = Gsx7(1);
                attiqubState.RW_Gsx(7,1) = Gsx7(1);
                app.Y7EditField.Enable = 'On';
                app.Y7EditField.Editable = 'On';
                app.Y7EditField.Value = Gsx7(2);
                attiqubState.RW_Gsx(7,2) = Gsx7(2);
                app.Z7EditField.Enable = 'On';
                app.Z7EditField.Editable = 'On';
                app.Z7EditField.Value = Gsx7(3);
                attiqubState.RW_Gsx(7,3) = Gsx7(3);
                app.V7EditField.Enable = 'On';
                app.V7EditField.Editable = 'On';
                attiqubState.RW_Ohm(7) = app.V7EditField.Value;
            end
            if attiqubState.RW_Num >= 8
                Gsx8 = [ app.X8EditField.Value ...
                         app.Y8EditField.Value ...
                         app.Z8EditField.Value ];
                if ( Gsx8 * Gsx8' ) ~= 0
                    Gsx8 = Gsx8 / norm( Gsx8 );
                end
                app.X8EditField.Enable = 'On';
                app.X8EditField.Editable = 'On';
                app.X8EditField.Value = Gsx8(1);
                attiqubState.RW_Gsx(8,1) = Gsx8(1);
                app.Y8EditField.Enable = 'On';
                app.Y8EditField.Editable = 'On';
                app.Y8EditField.Value = Gsx8(2);
                attiqubState.RW_Gsx(8,2) = Gsx8(2);
                app.Z8EditField.Enable = 'On';
                app.Z8EditField.Editable = 'On';
                app.Z8EditField.Value = Gsx8(3);
                attiqubState.RW_Gsx(8,3) = Gsx8(3);
                app.V8EditField.Enable = 'On';
                app.V8EditField.Editable = 'On';
                attiqubState.RW_Ohm(8) = app.V8EditField.Value;
            end
            
            % Save the individual reaction wheel principal inertias
            rwX = app.RXEditField.Value;
            rwY = app.RYEditField.Value;
            rwZ = app.RZEditField.Value;
            attiqubState.rwInertia = diag( [ rwX rwY rwZ ] );
            
            % Save the disturbance torque values.
            torqX = app.TXEditField.Value;
            torqY = app.TYEditField.Value;
            torqZ = app.TZEditField.Value;
            attiqubState.satTorque = [ torqX torqY torqZ ];
            
            % Save the parameters.
            save('parameters.mat', '-struct', 'attiqubState');
            
        end
        
        function loadState(app)
            
            % Load the parameters. Fill in all the edit fields.
            load('parameters.mat');
            
            % Load the simulation time duration and steps.
            app.SimulationTimesEditField.Value = tFinal;
            app.TimeStepsEditField.Value = tStepD;
            app.ControlRateHzEditField.Value = 1 / tStepC;
            
            % Load the choice of central body.
            if body == 1
                app.CentralBodyDropDown.Value = '1';
            elseif body == 2
                app.CentralBodyDropDown.Value = '2';
            elseif body == 3
                app.CentralBodyDropDown.Value = '3';
            elseif body == 4
                app.CentralBodyDropDown.Value = '4';
            elseif body == 5
                app.CentralBodyDropDown.Value = '5';
            else
                app.CentralBodyDropDown.Value = 'Earth';
            end
            
            % Load the spacecraft principal inertias.
            app.IXEditField.Value = satInertia(1,1);
            app.IYEditField.Value = satInertia(2,2);
            app.IZEditField.Value = satInertia(3,3);
            
            % Load user defined attitude coordinate choices.
            if satAttCoord == 1
                app.QuarternionsButton.Value = true;
                app.ClassicalRodriguesParametersButton.Value = false;
                app.ModifiedRodriguesParametersButton.Value = false;
            elseif satAttCoord == 2
                app.QuarternionsButton.Value = false;
                app.ClassicalRodriguesParametersButton.Value = true;
                app.ModifiedRodriguesParametersButton.Value = false;
            elseif satAttCoord == 3
                app.QuarternionsButton.Value = false;
                app.ClassicalRodriguesParametersButton.Value = false;
                app.ModifiedRodriguesParametersButton.Value = true;
            else
                app.QuarternionsButton.Value = false;
                app.ClassicalRodriguesParametersButton.Value = false;
                app.ModifiedRodriguesParametersButton.Value = false;
            end
            
            % Load user defined control law choices.
            if ctrlLaw == 1
                app.PDFeedbackControlButton.Value = true;
                app.PIDFeedbackControlButton.Value = false;
                app.ModelPredictiveControlButton.Value = false;
            elseif ctrlLaw == 2
                app.PDFeedbackControlButton.Value = false;
                app.PIDFeedbackControlButton.Value = true;
                app.ModelPredictiveControlButton.Value = false;
                
            elseif ctrlLaw == 3
                app.PDFeedbackControlButton.Value = false;
                app.PIDFeedbackControlButton.Value = false;
                app.ModelPredictiveControlButton.Value = true;
            else
                app.PDFeedbackControlButton.Value = false;
                app.PIDFeedbackControlButton.Value = false;
                app.ModelPredictiveControlButton.Value = false;
            end
            
            % Load user defined control mode choices.
            if satPointing == 1
                app.NadirPointingButton.Value = true;
                app.SunPointingButton.Value = false;
                app.InterSatelliteCommsButton.Value = false;
                app.DeTumblingButton.Value = false;
            elseif satPointing == 2
                app.NadirPointingButton.Value = false;
                app.SunPointingButton.Value = true;
                app.InterSatelliteCommsButton.Value = false;
                app.DeTumblingButton.Value = false;
            elseif satPointing == 3
                app.NadirPointingButton.Value = false;
                app.SunPointingButton.Value = false;
                app.InterSatelliteCommsButton.Value = true;
                app.DeTumblingButton.Value = false;
            elseif satPointing == 4
                app.NadirPointingButton.Value = false;
                app.SunPointingButton.Value = false;
                app.InterSatelliteCommsButton.Value = false;
                app.DeTumblingButton.Value = true;
            else
                app.NadirPointingButton.Value = false;
                app.SunPointingButton.Value = false;
                app.InterSatelliteCommsButton.Value = false;
                app.DeTumblingButton.Value = true;
            end
            
            % Load control gains (for PD and PID mode).
            app.KPEditField.Value = Kp;
            app.KIEditField.Value = Ki;
            app.KDEditField.Value = Kd;
            
            % Load the chief spacecraft orbit.
            app.SemiMajorAxismEditField.Value = orb1A;
            app.EccentricityEditField.Value = orb1E;
            app.InclinationdegEditField.Value = orb1I;
            app.RAANdegEditField.Value = orb1R;
            app.ArgPeriapsisdegEditField.Value = orb1W;
            app.MeanAnomalydegEditField.Value = orb1M;
            
            % Load the deputy spacecraft orbit.
            app.SemiMajorAxismEditField_2.Value = orb2A;
            app.EccentricityEditField_2.Value = orb2E;
            app.InclinationdegEditField_2.Value = orb2I;
            app.RAANdegEditField_2.Value = orb2R;
            app.ArgPeriapsisdegEditField_2.Value = orb2W;
            app.MeanAnomalydegEditField_2.Value = orb2M;
            
            % Load the attitude coordinates.
            if satAttCoord == 1
                app.A0EditField.Enable = 'On';
                app.A0EditField.Editable = 'On';
                app.A0EditField.Value = ATT_BN(1);
                app.A1EditField.Value = ATT_BN(2);
                app.A2EditField.Value = ATT_BN(3);
                app.A3EditField.Value = ATT_BN(4);
            else
                app.A0EditField.Enable = 'Off';
                app.A0EditField.Editable = 'Off';
                app.A0EditField.Value = 0;
                app.A1EditField.Value = ATT_BN(1);
                app.A2EditField.Value = ATT_BN(2);
                app.A3EditField.Value = ATT_BN(3);
            end
            
            % Load the angular velocities.
            app.O1EditField.Value = OHM_BN(1);
            app.O2EditField.Value = OHM_BN(2);
            app.O3EditField.Value = OHM_BN(3);
            
            % Load the reaction wheel parameters.
            app.NoReactionWheelsEditField.Value = RW_Num;
            app.MaximumSpinTorqueEditField.Value = RW_MxT;
            app.MaximumSpinRateEditField.Value = RW_MxR;
            
            % First, disable all reaction wheel spin axes edit fields.
            app.disable_zero_RWGsx()
            RW_Gsx;
            RW_Ohm;
            
            % Load the reaction wheel spin axes (from 1 to 8).
            if RW_Num >= 1
                Gsx1 = [ RW_Gsx(1,1) RW_Gsx(1,2) RW_Gsx(1,3) ];
                if ( Gsx1 * Gsx1' ) ~= 0
                    Gsx1 = Gsx1 / norm( Gsx1 );
                end
                app.X1EditField.Enable = 'On';
                app.X1EditField.Editable = 'On';
                app.X1EditField.Value = Gsx1(1);
                app.Y1EditField.Enable = 'On';
                app.Y1EditField.Editable = 'On';
                app.Y1EditField.Value = Gsx1(2);
                app.Z1EditField.Enable = 'On';
                app.Z1EditField.Editable = 'On';
                app.Z1EditField.Value = Gsx1(3);
                app.V1EditField.Enable = 'On';
                app.V1EditField.Editable = 'On';
                app.V1EditField.Value = RW_Ohm(1);
            end
            if RW_Num >= 2
                Gsx2 = [ RW_Gsx(2,1) RW_Gsx(2,2) RW_Gsx(2,3) ];
                if ( Gsx2 * Gsx2' ) ~= 0
                    Gsx2 = Gsx2 / norm( Gsx2 );
                end
                app.X2EditField.Enable = 'On';
                app.X2EditField.Editable = 'On';
                app.X2EditField.Value = Gsx2(1);
                app.Y2EditField.Enable = 'On';
                app.Y2EditField.Editable = 'On';
                app.Y2EditField.Value = Gsx2(2);
                app.Z2EditField.Enable = 'On';
                app.Z2EditField.Editable = 'On';
                app.Z2EditField.Value = Gsx2(3);
                app.V2EditField.Enable = 'On';
                app.V2EditField.Editable = 'On';
                app.V2EditField.Value = RW_Ohm(2);
            end
            if RW_Num >= 3
                Gsx3 = [ RW_Gsx(3,1) RW_Gsx(3,2) RW_Gsx(3,3) ];
                if ( Gsx3 * Gsx3' ) ~= 0
                    Gsx3 = Gsx3 / norm( Gsx3 );
                end
                app.X3EditField.Enable = 'On';
                app.X3EditField.Editable = 'On';
                app.X3EditField.Value = Gsx3(1);
                app.Y3EditField.Enable = 'On';
                app.Y3EditField.Editable = 'On';
                app.Y3EditField.Value = Gsx3(2);
                app.Z3EditField.Enable = 'On';
                app.Z3EditField.Editable = 'On';
                app.Z3EditField.Value = Gsx3(3);
                app.V3EditField.Enable = 'On';
                app.V3EditField.Editable = 'On';
                app.V3EditField.Value = RW_Ohm(3);
            end
            if RW_Num >= 4
                Gsx4 = [ RW_Gsx(4,1) RW_Gsx(4,2) RW_Gsx(4,3) ];
                if ( Gsx4 * Gsx4' ) ~= 0
                    Gsx4 = Gsx4 / norm( Gsx4 );
                end
                app.X4EditField.Enable = 'On';
                app.X4EditField.Editable = 'On';
                app.X4EditField.Value = Gsx4(1);
                app.Y4EditField.Enable = 'On';
                app.Y4EditField.Editable = 'On';
                app.Y4EditField.Value = Gsx4(2);
                app.Z4EditField.Enable = 'On';
                app.Z4EditField.Editable = 'On';
                app.Z4EditField.Value = Gsx4(3);
                app.V4EditField.Enable = 'On';
                app.V4EditField.Editable = 'On';
                app.V4EditField.Value = RW_Ohm(4);
            end
            if RW_Num >= 5
                Gsx5 = [ RW_Gsx(5,1) RW_Gsx(5,2) RW_Gsx(5,3) ];
                if ( Gsx5 * Gsx5' ) ~= 0
                    Gsx5 = Gsx5 / norm( Gsx5 );
                end
                app.X5EditField.Enable = 'On';
                app.X5EditField.Editable = 'On';
                app.X5EditField.Value = Gsx5(1);
                app.Y5EditField.Enable = 'On';
                app.Y5EditField.Editable = 'On';
                app.Y5EditField.Value = Gsx5(2);
                app.Z5EditField.Enable = 'On';
                app.Z5EditField.Editable = 'On';
                app.Z5EditField.Value = Gsx5(3);
                app.V5EditField.Enable = 'On';
                app.V5EditField.Editable = 'On';
                app.V5EditField.Value = RW_Ohm(5);
            end
            if RW_Num >= 6
                Gsx6 = [ RW_Gsx(6,1) RW_Gsx(6,2) RW_Gsx(6,3) ];
                if ( Gsx6 * Gsx6' ) ~= 0
                    Gsx6 = Gsx6 / norm( Gsx6 );
                end
                app.X6EditField.Enable = 'On';
                app.X6EditField.Editable = 'On';
                app.X6EditField.Value = Gsx6(1);
                app.Y6EditField.Enable = 'On';
                app.Y6EditField.Editable = 'On';
                app.Y6EditField.Value = Gsx6(2);
                app.Z6EditField.Enable = 'On';
                app.Z6EditField.Editable = 'On';
                app.Z6EditField.Value = Gsx6(3);
                app.V6EditField.Enable = 'On';
                app.V6EditField.Editable = 'On';
                app.V6EditField.Value = RW_Ohm(6);
            end
            if RW_Num >= 7
                Gsx7 = [ RW_Gsx(7,1) RW_Gsx(7,2) RW_Gsx(7,3) ];
                if ( Gsx7 * Gsx7' ) ~= 0
                    Gsx7 = Gsx7 / norm( Gsx7 );
                end
                app.X7EditField.Enable = 'On';
                app.X7EditField.Editable = 'On';
                app.X7EditField.Value = Gsx7(1);
                app.Y7EditField.Enable = 'On';
                app.Y7EditField.Editable = 'On';
                app.Y7EditField.Value = Gsx7(2);
                app.Z7EditField.Enable = 'On';
                app.Z7EditField.Editable = 'On';
                app.Z7EditField.Value = Gsx7(3);
                app.V7EditField.Enable = 'On';
                app.V7EditField.Editable = 'On';
                app.V7EditField.Value = RW_Ohm(7);
            end
            if RW_Num >= 8
                Gsx8 = [ RW_Gsx(8,1) RW_Gsx(8,2) RW_Gsx(8,3) ];
                if ( Gsx8 * Gsx8' ) ~= 0
                    Gsx8 = Gsx8 / norm( Gsx8 );
                end
                app.X8EditField.Enable = 'On';
                app.X8EditField.Editable = 'On';
                app.X8EditField.Value = Gsx8(1);
                app.Y8EditField.Enable = 'On';
                app.Y8EditField.Editable = 'On';
                app.Y8EditField.Value = Gsx8(2);
                app.Z8EditField.Enable = 'On';
                app.Z8EditField.Editable = 'On';
                app.Z8EditField.Value = Gsx8(3);
                app.V8EditField.Enable = 'On';
                app.V8EditField.Editable = 'On';
                app.V8EditField.Value = RW_Ohm(8);
            end
            
            % Load the individual reaction wheel principal inertias
            app.RXEditField.Value = rwInertia(1);
            app.RYEditField.Value = rwInertia(2);
            app.RZEditField.Value = rwInertia(3);
            
            % Load the disturbance torque values.
            app.TXEditField.Value = satTorque(1);
            app.TYEditField.Value = satTorque(2);
            app.TZEditField.Value = satTorque(3);
        end
        
        function disable_zero_RWGsx(app)
            
            app.X1EditField.Enable = 'Off';
            app.X1EditField.Editable = 'Off';
            app.X1EditField.Value = 0;
            app.Y1EditField.Enable = 'Off';
            app.Y1EditField.Editable = 'Off';
            app.Y1EditField.Value = 0;
            app.Z1EditField.Enable = 'Off';
            app.Z1EditField.Editable = 'Off';
            app.Z1EditField.Value = 0;
            app.V1EditField.Enable = 'Off';
            app.V1EditField.Editable = 'Off';
            app.V1EditField.Value = 0;
            
            app.X2EditField.Enable = 'Off';
            app.X2EditField.Editable = 'Off';
            app.X2EditField.Value = 0;
            app.Y2EditField.Enable = 'Off';
            app.Y2EditField.Editable = 'Off';
            app.Y2EditField.Value = 0;
            app.Z2EditField.Enable = 'Off';
            app.Z2EditField.Editable = 'Off';
            app.Z2EditField.Value = 0;
            app.V2EditField.Enable = 'Off';
            app.V2EditField.Editable = 'Off';
            app.V2EditField.Value = 0;
            
            app.X3EditField.Enable = 'Off';
            app.X3EditField.Editable = 'Off';
            app.X3EditField.Value = 0;
            app.Y3EditField.Enable = 'Off';
            app.Y3EditField.Editable = 'Off';
            app.Y3EditField.Value = 0;
            app.Z3EditField.Enable = 'Off';
            app.Z3EditField.Editable = 'Off';
            app.Z3EditField.Value = 0;
            app.V3EditField.Enable = 'Off';
            app.V3EditField.Editable = 'Off';
            app.V3EditField.Value = 0;
            
            app.X4EditField.Enable = 'Off';
            app.X4EditField.Editable = 'Off';
            app.X4EditField.Value = 0;
            app.Y4EditField.Enable = 'Off';
            app.Y4EditField.Editable = 'Off';
            app.Y4EditField.Value = 0;
            app.Z4EditField.Enable = 'Off';
            app.Z4EditField.Editable = 'Off';
            app.Z4EditField.Value = 0;
            app.V4EditField.Enable = 'Off';
            app.V4EditField.Editable = 'Off';
            app.V4EditField.Value = 0;
            
            app.X5EditField.Enable = 'Off';
            app.X5EditField.Editable = 'Off';
            app.X5EditField.Value = 0;
            app.Y5EditField.Enable = 'Off';
            app.Y5EditField.Editable = 'Off';
            app.Y5EditField.Value = 0;
            app.Z5EditField.Enable = 'Off';
            app.Z5EditField.Editable = 'Off';
            app.Z5EditField.Value = 0;
            app.V5EditField.Enable = 'Off';
            app.V5EditField.Editable = 'Off';
            app.V5EditField.Value = 0;
            
            app.X6EditField.Enable = 'Off';
            app.X6EditField.Editable = 'Off';
            app.X6EditField.Value = 0;
            app.Y6EditField.Enable = 'Off';
            app.Y6EditField.Editable = 'Off';
            app.Y6EditField.Value = 0;
            app.Z6EditField.Enable = 'Off';
            app.Z6EditField.Editable = 'Off';
            app.Z6EditField.Value = 0;
            app.V6EditField.Enable = 'Off';
            app.V6EditField.Editable = 'Off';
            app.V6EditField.Value = 0;
            
            app.X7EditField.Enable = 'Off';
            app.X7EditField.Editable = 'Off';
            app.X7EditField.Value = 0;
            app.Y7EditField.Enable = 'Off';
            app.Y7EditField.Editable = 'Off';
            app.Y7EditField.Value = 0;
            app.Z7EditField.Enable = 'Off';
            app.Z7EditField.Editable = 'Off';
            app.Z7EditField.Value = 0;
            app.V7EditField.Enable = 'Off';
            app.V7EditField.Editable = 'Off';
            app.V7EditField.Value = 0;
            
            app.X8EditField.Enable = 'Off';
            app.X8EditField.Editable = 'Off';
            app.X8EditField.Value = 0;
            app.Y8EditField.Enable = 'Off';
            app.Y8EditField.Editable = 'Off';
            app.Y8EditField.Value = 0;
            app.Z8EditField.Enable = 'Off';
            app.Z8EditField.Editable = 'Off';
            app.Z8EditField.Value = 0;
            app.V8EditField.Enable = 'Off';
            app.V8EditField.Editable = 'Off';
            app.V8EditField.Value = 0;
            
        end
        
        function disable_RWGsx(app)
            
            app.X1EditField.Enable = 'Off';
            app.X1EditField.Editable = 'Off';
            app.Y1EditField.Enable = 'Off';
            app.Y1EditField.Editable = 'Off';
            app.Z1EditField.Enable = 'Off';
            app.Z1EditField.Editable = 'Off';
            app.V1EditField.Enable = 'Off';
            app.V1EditField.Editable = 'Off';
            
            app.X2EditField.Enable = 'Off';
            app.X2EditField.Editable = 'Off';
            app.Y2EditField.Enable = 'Off';
            app.Y2EditField.Editable = 'Off';
            app.Z2EditField.Enable = 'Off';
            app.Z2EditField.Editable = 'Off';
            app.V2EditField.Enable = 'Off';
            app.V2EditField.Editable = 'Off';
            
            app.X3EditField.Enable = 'Off';
            app.X3EditField.Editable = 'Off';
            app.Y3EditField.Enable = 'Off';
            app.Y3EditField.Editable = 'Off';
            app.Z3EditField.Enable = 'Off';
            app.Z3EditField.Editable = 'Off';
            app.V3EditField.Enable = 'Off';
            app.V3EditField.Editable = 'Off';
            
            app.X4EditField.Enable = 'Off';
            app.X4EditField.Editable = 'Off';
            app.Y4EditField.Enable = 'Off';
            app.Y4EditField.Editable = 'Off';
            app.Z4EditField.Enable = 'Off';
            app.Z4EditField.Editable = 'Off';
            app.V4EditField.Enable = 'Off';
            app.V4EditField.Editable = 'Off';
            
            app.X5EditField.Enable = 'Off';
            app.X5EditField.Editable = 'Off';
            app.Y5EditField.Enable = 'Off';
            app.Y5EditField.Editable = 'Off';
            app.Z5EditField.Enable = 'Off';
            app.Z5EditField.Editable = 'Off';
            app.V5EditField.Enable = 'Off';
            app.V5EditField.Editable = 'Off';
            
            app.X6EditField.Enable = 'Off';
            app.X6EditField.Editable = 'Off';
            app.Y6EditField.Enable = 'Off';
            app.Y6EditField.Editable = 'Off';
            app.Z6EditField.Enable = 'Off';
            app.Z6EditField.Editable = 'Off';
            app.V6EditField.Enable = 'Off';
            app.V6EditField.Editable = 'Off';
            
            app.X7EditField.Enable = 'Off';
            app.X7EditField.Editable = 'Off';
            app.Y7EditField.Enable = 'Off';
            app.Y7EditField.Editable = 'Off';
            app.Z7EditField.Enable = 'Off';
            app.Z7EditField.Editable = 'Off';
            app.V7EditField.Enable = 'Off';
            app.V7EditField.Editable = 'Off';
            
            app.X8EditField.Enable = 'Off';
            app.X8EditField.Editable = 'Off';
            app.Y8EditField.Enable = 'Off';
            app.Y8EditField.Editable = 'Off';
            app.Z8EditField.Enable = 'Off';
            app.Z8EditField.Editable = 'Off';
            app.V8EditField.Enable = 'Off';
            app.V8EditField.Editable = 'Off';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Load all previous states
                app.loadState;
            catch
            end
            
            % ATTQUB must resolve all file paths in order to access libraries.
            attqubPath = mfilename('fullpath');
            [directory, ~, ~]  = fileparts(attqubPath);
            srcPaths = {[ directory '\source\'                    ];  ...
                        [ directory '\images\'                  ];
                        [ directory '\source\attiqub_att_ctrl\'   ];  ...
                        [ directory '\source\attiqub_att_refn\'   ];  ...
                        [ directory '\source\attiqub_coord_dcm\'  ];  ...
                        [ directory '\source\attiqub_coord_qrt\'  ];  ...
                        [ directory '\source\attiqub_coord_crp\'  ];  ...
                        [ directory '\source\attiqub_coord_mrp\'  ];  ...
                        [ directory '\source\attiqub_orbit_prop\' ];  ...
                        [ directory '\source\attiqub_plot_body\'  ];  ...
                        [ directory '\source\attiqub_plot_result\']};
                    
            % Add all the paths in the srcPaths cell array.
            for n = 1 : length(srcPaths)
                path = string(srcPaths(n));
                addpath(path);
            end
        end

        % Button pushed function: SAVEPARAMETERSButton
        function SAVEPARAMETERSButtonPushed(app, event)
            app.saveState;
        end

        % Button pushed function: LOADPARAMETERSButton
        function LOADPARAMETERSButtonPushed(app, event)
            app.loadState;
        end

        % Selection changed function: 
        % ChiefSpacecraftAttitudeButtonGroup
        function ChiefSpacecraftAttitudeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChiefSpacecraftAttitudeButtonGroup.SelectedObject;
            if app.QuarternionsButton.Value == true
                app.A0EditField.Enable = 'On';
                app.A0EditField.Editable = 'On';
            elseif app.ClassicalRodriguesParametersButton.Value == true
                app.A0EditField.Enable = 'Off';
                app.A0EditField.Editable = 'Off';
            elseif app.ModifiedRodriguesParametersButton.Value == true
                app.A0EditField.Enable = 'Off';
                app.A0EditField.Editable = 'Off';
            end
        end

        % Value changed function: NoReactionWheelsEditField
        function NoReactionWheelsEditFieldValueChanged(app, event)
            value = app.NoReactionWheelsEditField.Value;
            disable_RWGsx(app);
            RW_Num = app.NoReactionWheelsEditField.Value;
            if RW_Num >= 1
                app.X1EditField.Enable = 'On';
                app.X1EditField.Editable = 'On';
                app.Y1EditField.Enable = 'On';
                app.Y1EditField.Editable = 'On';
                app.Z1EditField.Enable = 'On';
                app.Z1EditField.Editable = 'On';
                app.V1EditField.Enable = 'On';
                app.V1EditField.Editable = 'On';
            end
            if RW_Num >= 2
                app.X2EditField.Enable = 'On';
                app.X2EditField.Editable = 'On';
                app.Y2EditField.Enable = 'On';
                app.Y2EditField.Editable = 'On';
                app.Z2EditField.Enable = 'On';
                app.Z2EditField.Editable = 'On';
                app.V2EditField.Enable = 'On';
                app.V2EditField.Editable = 'On';
            end
            if RW_Num >= 3
                app.X3EditField.Enable = 'On';
                app.X3EditField.Editable = 'On';
                app.Y3EditField.Enable = 'On';
                app.Y3EditField.Editable = 'On';
                app.Z3EditField.Enable = 'On';
                app.Z3EditField.Editable = 'On';
                app.V3EditField.Enable = 'On';
                app.V3EditField.Editable = 'On';
            end
            if RW_Num >= 4
                app.X4EditField.Enable = 'On';
                app.X4EditField.Editable = 'On';
                app.Y4EditField.Enable = 'On';
                app.Y4EditField.Editable = 'On';
                app.Z4EditField.Enable = 'On';
                app.Z4EditField.Editable = 'On';
                app.V4EditField.Enable = 'On';
                app.V4EditField.Editable = 'On';
            end
            if RW_Num >= 5
                app.X5EditField.Enable = 'On';
                app.X5EditField.Editable = 'On';
                app.Y5EditField.Enable = 'On';
                app.Y5EditField.Editable = 'On';
                app.Z5EditField.Enable = 'On';
                app.Z5EditField.Editable = 'On';
                app.V5EditField.Enable = 'On';
                app.V5EditField.Editable = 'On';
            end
            if RW_Num >= 6
                app.X6EditField.Enable = 'On';
                app.X6EditField.Editable = 'On';
                app.Y6EditField.Enable = 'On';
                app.Y6EditField.Editable = 'On';
                app.Z6EditField.Enable = 'On';
                app.Z6EditField.Editable = 'On';
                app.V6EditField.Enable = 'On';
                app.V6EditField.Editable = 'On';
            end
            if RW_Num >= 7
                app.X7EditField.Enable = 'On';
                app.X7EditField.Editable = 'On';
                app.Y7EditField.Enable = 'On';
                app.Y7EditField.Editable = 'On';
                app.Z7EditField.Enable = 'On';
                app.Z7EditField.Editable = 'On';
                app.V7EditField.Enable = 'On';
                app.V7EditField.Editable = 'On';
            end
            if RW_Num >= 8
                app.X8EditField.Enable = 'On';
                app.X8EditField.Editable = 'On';
                app.Y8EditField.Enable = 'On';
                app.Y8EditField.Editable = 'On';
                app.Z8EditField.Enable = 'On';
                app.Z8EditField.Editable = 'On';
                app.V8EditField.Enable = 'On';
                app.V8EditField.Editable = 'On';
            end
            
        end

        % Button pushed function: LAUNCHButton
        function LAUNCHButtonPushed(app, event)
            saveState(app);
            attiqub_engine();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChiefSpacecraftAttitudeButtonGroup = uibuttongroup(app.UIFigure);
            app.ChiefSpacecraftAttitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChiefSpacecraftAttitudeButtonGroupSelectionChanged, true);
            app.ChiefSpacecraftAttitudeButtonGroup.Title = 'Chief Spacecraft Attitude';
            app.ModifiedRodriguesParametersButton = uitogglebutton(app.ChiefSpacecraftAttitudeButtonGroup);
            app.ModifiedRodriguesParametersButton.Text = 'Modified Rodrigues Parameters';
            app.ClassicalRodriguesParametersButton = uitogglebutton(app.ChiefSpacecraftAttitudeButtonGroup);
            app.ClassicalRodriguesParametersButton.Text = 'Classical Rodrigues Parameters';
            app.QuarternionsButton = uitogglebutton(app.ChiefSpacecraftAttitudeButtonGroup);
            app.QuarternionsButton.Text = 'Quarternions';
            app.QuarternionsButton.Value = true;
            app.ChiefSpacecraftOrbitLabel = uilabel(app.UIFigure);
            app.ChiefSpacecraftOrbitLabel.Text = 'Chief Spacecraft Orbit';
            app.DeputySpacecraftOrbitLabel = uilabel(app.UIFigure);
            app.DeputySpacecraftOrbitLabel.Text = 'Deputy Spacecraft Orbit';
            app.InitialAttitudeLabel = uilabel(app.UIFigure);
            app.InitialAttitudeLabel.Text = 'Initial Attitude';
            app.InitialOmegaLabel = uilabel(app.UIFigure);
            app.InitialOmegaLabel.Text = 'Initial Omega';
            app.ControlLawButtonGroup = uibuttongroup(app.UIFigure);
            app.ControlLawButtonGroup.Title = 'Control Law';
            app.PDFeedbackControlButton = uitogglebutton(app.ControlLawButtonGroup);
            app.PDFeedbackControlButton.Text = 'PD Feedback Control';
            app.PDFeedbackControlButton.Value = true;
            app.PIDFeedbackControlButton = uitogglebutton(app.ControlLawButtonGroup);
            app.PIDFeedbackControlButton.Text = 'PID Feedback Control';
            app.ModelPredictiveControlButton = uitogglebutton(app.ControlLawButtonGroup);
            app.ModelPredictiveControlButton.Text = 'Model Predictive Control';
            app.ControlModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ControlModeButtonGroup.Title = 'Control Mode';
            app.NadirPointingButton = uitogglebutton(app.ControlModeButtonGroup);
            app.NadirPointingButton.Text = 'Nadir Pointing';
            app.NadirPointingButton.Value = true;
            app.SunPointingButton = uitogglebutton(app.ControlModeButtonGroup);
            app.SunPointingButton.Text = 'Sun Pointing';
            app.InterSatelliteCommsButton = uitogglebutton(app.ControlModeButtonGroup);
            app.InterSatelliteCommsButton.Text = 'Inter-Satellite Comms';
            app.DeTumblingButton = uitogglebutton(app.ControlModeButtonGroup);
            app.DeTumblingButton.Text = 'De-Tumbling';
            app.LAUNCHButton = uibutton(app.UIFigure, 'push');
            app.LAUNCHButton.ButtonPushedFcn = createCallbackFcn(app, @LAUNCHButtonPushed, true);
            app.LAUNCHButton.Text = 'LAUNCH!';
            app.SimulationSetupLabel = uilabel(app.UIFigure);
            app.SimulationSetupLabel.Text = 'Simulation Setup';
            app.ReactionWheelAssemblyLabel = uilabel(app.UIFigure);
            app.ReactionWheelAssemblyLabel.Text = 'Reaction Wheel Assembly';
            app.SimulationTimesEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationTimesEditFieldLabel.Text = 'Simulation Time (s)';
            app.SimulationTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationTimesEditField.Limits = [0 Inf];
            app.SimulationTimesEditField.Tag = 'tStart';
            app.TimeStepsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepsEditFieldLabel.Text = 'Time Step (s)';
            app.TimeStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepsEditField.Limits = [0 Inf];
            app.TimeStepsEditField.Tag = 'tStepD';
            app.CentralBodyDropDownLabel = uilabel(app.UIFigure);
            app.CentralBodyDropDownLabel.Text = 'Central Body';
            app.CentralBodyDropDown = uidropdown(app.UIFigure);
            app.CentralBodyDropDown.Items = {'Earth', 'Moon', 'Mars', 'Venus', 'Mercury'};
            app.CentralBodyDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.CentralBodyDropDown.Tag = 'body';
            app.CentralBodyDropDown.Value = '1';
            app.SemiMajorAxismEditFieldLabel = uilabel(app.UIFigure);
            app.SemiMajorAxismEditFieldLabel.Text = 'Semi-Major Axis (m)';
            app.SemiMajorAxismEditField = uieditfield(app.UIFigure, 'numeric');
            app.SemiMajorAxismEditField.Limits = [0 Inf];
            app.EccentricityEditFieldLabel = uilabel(app.UIFigure);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.UIFigure, 'numeric');
            app.EccentricityEditField.Limits = [0 1];
            app.InclinationdegEditFieldLabel = uilabel(app.UIFigure);
            app.InclinationdegEditFieldLabel.Text = 'Inclination (deg)';
            app.InclinationdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.InclinationdegEditField.Limits = [-90 90];
            app.RAANdegEditFieldLabel = uilabel(app.UIFigure);
            app.RAANdegEditFieldLabel.Text = 'RAAN (deg)';
            app.RAANdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.RAANdegEditField.Limits = [0 360];
            app.ArgPeriapsisdegEditFieldLabel = uilabel(app.UIFigure);
            app.ArgPeriapsisdegEditFieldLabel.Text = 'Arg. Periapsis (deg)';
            app.ArgPeriapsisdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArgPeriapsisdegEditField.Limits = [0 360];
            app.MeanAnomalydegEditFieldLabel = uilabel(app.UIFigure);
            app.MeanAnomalydegEditFieldLabel.Text = 'Mean Anomaly (deg)';
            app.MeanAnomalydegEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanAnomalydegEditField.Limits = [0 360];
            app.A0EditFieldLabel = uilabel(app.UIFigure);
            app.A0EditFieldLabel.Text = 'A0';
            app.A0EditField = uieditfield(app.UIFigure, 'numeric');
            app.A0EditField.Value = 1;
            app.A1EditField_5Label = uilabel(app.UIFigure);
            app.A1EditField_5Label.Text = 'A1';
            app.A1EditField = uieditfield(app.UIFigure, 'numeric');
            app.A2EditFieldLabel = uilabel(app.UIFigure);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.UIFigure, 'numeric');
            app.A3EditFieldLabel = uilabel(app.UIFigure);
            app.A3EditFieldLabel.Text = 'A3';
            app.A3EditField = uieditfield(app.UIFigure, 'numeric');
            app.O1EditFieldLabel = uilabel(app.UIFigure);
            app.O1EditFieldLabel.Text = 'O1';
            app.O1EditField = uieditfield(app.UIFigure, 'numeric');
            app.O2EditFieldLabel = uilabel(app.UIFigure);
            app.O2EditFieldLabel.Text = 'O2';
            app.O2EditField = uieditfield(app.UIFigure, 'numeric');
            app.O3EditFieldLabel = uilabel(app.UIFigure);
            app.O3EditFieldLabel.Text = 'O3';
            app.O3EditField = uieditfield(app.UIFigure, 'numeric');
            app.SemiMajorAxismEditField_2Label = uilabel(app.UIFigure);
            app.SemiMajorAxismEditField_2Label.Text = 'Semi-Major Axis (m)';
            app.SemiMajorAxismEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SemiMajorAxismEditField_2.Limits = [0 Inf];
            app.EccentricityEditField_2Label = uilabel(app.UIFigure);
            app.EccentricityEditField_2Label.Text = 'Eccentricity';
            app.EccentricityEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EccentricityEditField_2.Limits = [0 1];
            app.InclinationdegEditField_2Label = uilabel(app.UIFigure);
            app.InclinationdegEditField_2Label.Text = 'Inclination (deg)';
            app.InclinationdegEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.InclinationdegEditField_2.Limits = [-90 90];
            app.RAANdegEditField_2Label = uilabel(app.UIFigure);
            app.RAANdegEditField_2Label.Text = 'RAAN (deg)';
            app.RAANdegEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RAANdegEditField_2.Limits = [0 360];
            app.ArgPeriapsisdegEditField_2Label = uilabel(app.UIFigure);
            app.ArgPeriapsisdegEditField_2Label.Text = 'Arg. Periapsis (deg)';
            app.ArgPeriapsisdegEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ArgPeriapsisdegEditField_2.Limits = [0 360];
            app.MeanAnomalydegEditField_2Label = uilabel(app.UIFigure);
            app.MeanAnomalydegEditField_2Label.Text = 'Mean Anomaly (deg)';
            app.MeanAnomalydegEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MeanAnomalydegEditField_2.Limits = [0 360];
            app.KPEditFieldLabel = uilabel(app.UIFigure);
            app.KPEditFieldLabel.Text = 'KP';
            app.KPEditField = uieditfield(app.UIFigure, 'numeric');
            app.KIEditFieldLabel = uilabel(app.UIFigure);
            app.KIEditFieldLabel.Text = 'KI';
            app.KIEditField = uieditfield(app.UIFigure, 'numeric');
            app.KDEditFieldLabel = uilabel(app.UIFigure);
            app.KDEditFieldLabel.Text = 'KD';
            app.KDEditField = uieditfield(app.UIFigure, 'numeric');
            app.ControlRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.ControlRateHzEditFieldLabel.Text = 'Control Rate (Hz)';
            app.ControlRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.ControlRateHzEditField.Limits = [0.001 1000000000];
            app.ControlRateHzEditField.Tag = 'tStepD';
            app.ControlRateHzEditField.Value = 0.001;
            app.NoReactionWheelsEditFieldLabel = uilabel(app.UIFigure);
            app.NoReactionWheelsEditFieldLabel.Text = 'No. Reaction Wheels';
            app.NoReactionWheelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoReactionWheelsEditField.Limits = [0 8];
            app.NoReactionWheelsEditField.ValueChangedFcn = createCallbackFcn(app, @NoReactionWheelsEditFieldValueChanged, true);
            app.MaximumSpinTorqueEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumSpinTorqueEditFieldLabel.Text = 'Maximum Spin Torque';
            app.MaximumSpinTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumSpinTorqueEditField.Limits = [0 Inf];
            app.MaximumSpinRateEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumSpinRateEditFieldLabel.Text = 'Maximum Spin Rate';
            app.MaximumSpinRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumSpinRateEditField.Limits = [0 Inf];
            app.ReactionWheelSpinAxesLabel = uilabel(app.UIFigure);
            app.ReactionWheelSpinAxesLabel.Text = 'Reaction Wheel Spin Axes';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditFieldLabel = uilabel(app.UIFigure);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditFieldLabel = uilabel(app.UIFigure);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X3EditFieldLabel = uilabel(app.UIFigure);
            app.X3EditFieldLabel.Text = 'X3';
            app.X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y3EditFieldLabel = uilabel(app.UIFigure);
            app.Y3EditFieldLabel.Text = 'Y3';
            app.Y3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z3EditFieldLabel = uilabel(app.UIFigure);
            app.Z3EditFieldLabel.Text = 'Z3';
            app.Z3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X4EditFieldLabel = uilabel(app.UIFigure);
            app.X4EditFieldLabel.Text = 'X4';
            app.X4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y4EditFieldLabel = uilabel(app.UIFigure);
            app.Y4EditFieldLabel.Text = 'Y4';
            app.Y4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z4EditFieldLabel = uilabel(app.UIFigure);
            app.Z4EditFieldLabel.Text = 'Z4';
            app.Z4EditField = uieditfield(app.UIFigure, 'numeric');
            app.X5EditFieldLabel = uilabel(app.UIFigure);
            app.X5EditFieldLabel.Text = 'X5';
            app.X5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y5EditFieldLabel = uilabel(app.UIFigure);
            app.Y5EditFieldLabel.Text = 'Y5';
            app.Y5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z5EditFieldLabel = uilabel(app.UIFigure);
            app.Z5EditFieldLabel.Text = 'Z5';
            app.Z5EditField = uieditfield(app.UIFigure, 'numeric');
            app.X6EditFieldLabel = uilabel(app.UIFigure);
            app.X6EditFieldLabel.Text = 'X6';
            app.X6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y6EditFieldLabel = uilabel(app.UIFigure);
            app.Y6EditFieldLabel.Text = 'Y6';
            app.Y6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z6EditFieldLabel = uilabel(app.UIFigure);
            app.Z6EditFieldLabel.Text = 'Z6';
            app.Z6EditField = uieditfield(app.UIFigure, 'numeric');
            app.X7EditFieldLabel = uilabel(app.UIFigure);
            app.X7EditFieldLabel.Text = 'X7';
            app.X7EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y7EditFieldLabel = uilabel(app.UIFigure);
            app.Y7EditFieldLabel.Text = 'Y7';
            app.Y7EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z7EditFieldLabel = uilabel(app.UIFigure);
            app.Z7EditFieldLabel.Text = 'Z7';
            app.Z7EditField = uieditfield(app.UIFigure, 'numeric');
            app.X8EditFieldLabel = uilabel(app.UIFigure);
            app.X8EditFieldLabel.Text = 'X8';
            app.X8EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y8EditFieldLabel = uilabel(app.UIFigure);
            app.Y8EditFieldLabel.Text = 'Y8';
            app.Y8EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z8EditFieldLabel = uilabel(app.UIFigure);
            app.Z8EditFieldLabel.Text = 'Z8';
            app.Z8EditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'attiqub.png';
            app.DisturbanceTorqueNmLabel = uilabel(app.UIFigure);
            app.DisturbanceTorqueNmLabel.Text = 'Disturbance Torque (Nm)';
            app.TXEditFieldLabel = uilabel(app.UIFigure);
            app.TXEditFieldLabel.Text = 'TX';
            app.TXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TYEditFieldLabel = uilabel(app.UIFigure);
            app.TYEditFieldLabel.Text = 'TY';
            app.TYEditField = uieditfield(app.UIFigure, 'numeric');
            app.TZEditFieldLabel = uilabel(app.UIFigure);
            app.TZEditFieldLabel.Text = 'TZ';
            app.TZEditField = uieditfield(app.UIFigure, 'numeric');
            app.SAVEPARAMETERSButton = uibutton(app.UIFigure, 'push');
            app.SAVEPARAMETERSButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEPARAMETERSButtonPushed, true);
            app.SAVEPARAMETERSButton.Text = 'SAVE PARAMETERS';
            app.LOADPARAMETERSButton = uibutton(app.UIFigure, 'push');
            app.LOADPARAMETERSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPARAMETERSButtonPushed, true);
            app.LOADPARAMETERSButton.Text = 'LOAD PARAMETERS';
            app.ChiefPrincipalInertiasLabel = uilabel(app.UIFigure);
            app.ChiefPrincipalInertiasLabel.Text = 'Chief Principal Inertias';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.IXEditField = uieditfield(app.UIFigure, 'numeric');
            app.IXEditField.Limits = [0 Inf];
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.IYEditField = uieditfield(app.UIFigure, 'numeric');
            app.IYEditField.Limits = [0 Inf];
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.IZEditField = uieditfield(app.UIFigure, 'numeric');
            app.IZEditField.Limits = [0 Inf];
            app.V1EditFieldLabel = uilabel(app.UIFigure);
            app.V1EditFieldLabel.Text = 'V1';
            app.V1EditField = uieditfield(app.UIFigure, 'numeric');
            app.RateRPMLabel = uilabel(app.UIFigure);
            app.RateRPMLabel.Text = 'Rate (RPM)';
            app.InitialSpinLabel = uilabel(app.UIFigure);
            app.InitialSpinLabel.Text = 'Initial Spin';
            app.V2EditFieldLabel = uilabel(app.UIFigure);
            app.V2EditFieldLabel.Text = 'V2';
            app.V2EditField = uieditfield(app.UIFigure, 'numeric');
            app.V3EditFieldLabel = uilabel(app.UIFigure);
            app.V3EditFieldLabel.Text = 'V3';
            app.V3EditField = uieditfield(app.UIFigure, 'numeric');
            app.V4EditFieldLabel = uilabel(app.UIFigure);
            app.V4EditFieldLabel.Text = 'V4';
            app.V4EditField = uieditfield(app.UIFigure, 'numeric');
            app.V5EditFieldLabel = uilabel(app.UIFigure);
            app.V5EditFieldLabel.Text = 'V5';
            app.V5EditField = uieditfield(app.UIFigure, 'numeric');
            app.V6EditFieldLabel = uilabel(app.UIFigure);
            app.V6EditFieldLabel.Text = 'V6';
            app.V6EditField = uieditfield(app.UIFigure, 'numeric');
            app.V7EditFieldLabel = uilabel(app.UIFigure);
            app.V7EditFieldLabel.Text = 'V7';
            app.V7EditField = uieditfield(app.UIFigure, 'numeric');
            app.V8EditFieldLabel = uilabel(app.UIFigure);
            app.V8EditFieldLabel.Text = 'V8';
            app.V8EditField = uieditfield(app.UIFigure, 'numeric');
            app.Version05LastUpdated17April2021Label = uilabel(app.UIFigure);
            app.Version05LastUpdated17April2021Label.Text = 'Version 0.5 (Last Updated 17-April-2021)';
            app.IndividualReactionWheelPrincipalInertiakgm2Label = uilabel(app.UIFigure);
            app.IndividualReactionWheelPrincipalInertiakgm2Label.Text = 'Individual Reaction Wheel Principal Inertia (kg m^2)';
            app.RXEditFieldLabel = uilabel(app.UIFigure);
            app.RXEditFieldLabel.Text = 'RX';
            app.RXEditField = uieditfield(app.UIFigure, 'numeric');
            app.RYEditFieldLabel = uilabel(app.UIFigure);
            app.RYEditFieldLabel.Text = 'RY';
            app.RYEditField = uieditfield(app.UIFigure, 'numeric');
            app.RZEditFieldLabel = uilabel(app.UIFigure);
            app.RZEditFieldLabel.Text = 'RZ';
            app.RZEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = attiqub_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end